classdef roulette < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BILANCIOPARTITAEditField       matlab.ui.control.EditField
        BILANCIOPARTITAEditFieldLabel  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        ROULETTEPanel                  matlab.ui.container.Panel
        NumeroVincenteEditField        matlab.ui.control.NumericEditField
        NumeroVincenteEditFieldLabel   matlab.ui.control.Label
        HAIVINTOEditField              matlab.ui.control.NumericEditField
        HAIVINTOEditFieldLabel         matlab.ui.control.Label
        GIOCAButton                    matlab.ui.control.Button
        oNumeroesattoEditField         matlab.ui.control.NumericEditField
        oNumeroesattoEditFieldLabel    matlab.ui.control.Label
        CosascommettiDropDown          matlab.ui.control.DropDown
        CosascommettiDropDownLabel     matlab.ui.control.Label
        PuntataSpinner                 matlab.ui.control.Spinner
        PuntataSpinnerLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        Bilancio= 0 % Bilancio partita
    end
    
    methods (Access = private)
        
        function [vincita,esito,idx_vincente] = partita(app,set,puntata,modalita,scommessa)

            numeri = set.numeri;
            n = length(numeri);
            
            idx_vincente = randi(n);
            esito = numeri(idx_vincente);
            
            switch modalita
                case "Pari/Dispari"
                    if scommessa=="pari" && mod(esito,2)==0 && esito~=0
                        vincita = puntata*2;
                    elseif scommessa=="dispari" && mod(esito,2)==1
                        vincita = puntata*2;
                    else
                        vincita = 0;
                    end
                case "Rosso/Nero"
                    if esito==0
                        vincita=0;
                    elseif scommessa=="rosso" && ismember(esito,[32,19,21,25,34,27,36,30,23,5,16,1,14,9,18,7,12,3])
                        vincita=puntata*2;
                    elseif scommessa=="nero" && ismember(esito,[15,4,2,17,6,13,11,8,10,24,33,20,31,22,29,28,35,26])
                        vincita=puntata*2;
                    else
                        vincita=0;
                    end
                case "Numero esatto"
                    if esito==scommessa
                        vincita=puntata*36;
                    else
                        vincita=0;
                    end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GIOCAButton
        function GIOCAButtonPushed(app, event)
            set.numeri=[0,32,15,19,4,21,2,25,17,34,6,27,...
                13,36,11,30,8,23,10,5,24,16,33,1,20,14,...
                31,9,22,18,29,7,28,12,35,3,26];
                        
            puntata = app.PuntataSpinner.Value;
            modalita = app.CosascommettiDropDown.Value;
            switch modalita
                case 'Pari/Dispari'
                    if app.oNumeroesattoEditField.Value==1
                        scommessa = "pari";
                    elseif app.oNumeroesattoEditField.Value==2
                        scommessa = "dispari";
                    else
                        error('Hai inserito un numero non valido');
                    end
                case 'Rosso/Nero'
                    if app.oNumeroesattoEditField.Value==1
                        scommessa = "rosso";
                    elseif app.oNumeroesattoEditField.Value==2
                        scommessa = "nero";
                    else
                        error('Hai inserito un numero non valido');
                    end
                case 'Numero esatto'
                    scommessa = int8(app.oNumeroesattoEditField.Value);
            end
            [vincita,esito,idx_vincente] = partita(app,set,puntata,modalita,scommessa);

            x_center= app.Image.Position(1)+app.Image.Position(3)/2-app.Image2.Position(3)/2;
            y_center= app.Image.Position(2)+app.Image.Position(4)/2-app.Image2.Position(4)/2;
                        
            n = length(set.numeri);
            angoloVincente = 2*pi/n*(idx_vincente-1);
            theta=1.5*pi+angoloVincente-15:0.1:1.5*pi+angoloVincente;
            rho=100;
            
            for i=1:151
                [x,y] = pol2cart(theta(i),rho);
                pause(0.02 - (-4e-9/-0.11)*(1 - exp(0.11*i)))
                x=x+x_center;
                y=-y+y_center;
                app.Image2.Position=[x,y,19,22];
            end

            app.NumeroVincenteEditField.Value=esito;
            app.HAIVINTOEditField.Value=vincita;
            app.Bilancio=app.Bilancio+vincita-puntata;
            app.BILANCIOPARTITAEditField.Value=num2str(app.Bilancio);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ROULETTEPanel = uipanel(app.UIFigure);
            app.ROULETTEPanel.Title = 'ROULETTE';
            app.PuntataSpinnerLabel = uilabel(app.ROULETTEPanel);
            app.PuntataSpinnerLabel.Text = 'Puntata';
            app.PuntataSpinner = uispinner(app.ROULETTEPanel);
            app.PuntataSpinner.Limits = [0 Inf];
            app.CosascommettiDropDownLabel = uilabel(app.ROULETTEPanel);
            app.CosascommettiDropDownLabel.Text = 'Cosa scommetti?';
            app.CosascommettiDropDown = uidropdown(app.ROULETTEPanel);
            app.CosascommettiDropDown.Items = {'Pari/Dispari', 'Rosso/Nero', 'Numero esatto'};
            app.CosascommettiDropDown.Value = 'Pari/Dispari';
            app.oNumeroesattoEditFieldLabel = uilabel(app.ROULETTEPanel);
            app.oNumeroesattoEditFieldLabel.Text = '1/2 o Numero esatto';
            app.oNumeroesattoEditField = uieditfield(app.ROULETTEPanel, 'numeric');
            app.oNumeroesattoEditField.Limits = [1 36];
            app.oNumeroesattoEditField.Value = 1;
            app.GIOCAButton = uibutton(app.ROULETTEPanel, 'push');
            app.GIOCAButton.ButtonPushedFcn = createCallbackFcn(app, @GIOCAButtonPushed, true);
            app.GIOCAButton.Text = 'GIOCA';
            app.HAIVINTOEditFieldLabel = uilabel(app.ROULETTEPanel);
            app.HAIVINTOEditFieldLabel.Text = 'HAI VINTO';
            app.HAIVINTOEditField = uieditfield(app.ROULETTEPanel, 'numeric');
            app.NumeroVincenteEditFieldLabel = uilabel(app.ROULETTEPanel);
            app.NumeroVincenteEditFieldLabel.Text = 'Numero Vincente';
            app.NumeroVincenteEditField = uieditfield(app.ROULETTEPanel, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'roulette.jpeg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'pallina.png');
            app.BILANCIOPARTITAEditFieldLabel = uilabel(app.UIFigure);
            app.BILANCIOPARTITAEditFieldLabel.Text = 'BILANCIO PARTITA';
            app.BILANCIOPARTITAEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roulette

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end